% description: 1. Select a folder which contains STL files
% 2. Pick up a STL
% 3. Adjust Scale to change size of the STL file
% 4. Overwrite the STL file
% Copyright 2024 The MathWorks, Inc.
classdef StlScaleChanger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        ScaleEditField       matlab.ui.control.NumericEditField
        ScaleEditFieldLabel  matlab.ui.control.Label
        STLOverwriteButton   matlab.ui.control.Button
        GridLayout3          matlab.ui.container.GridLayout
        ScaleChangedUIAxes   matlab.ui.control.UIAxes
        PresentUIAxes        matlab.ui.control.UIAxes
        GridLayout2          matlab.ui.container.GridLayout
        ShowPresentCheckBox  matlab.ui.control.CheckBox
        StlFileListBox       matlab.ui.control.ListBox
        FolderSelectButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        tr1 % Present Triangulation
        tr2 % Reduced Triangulation
        filename % Present STL file name, full path
        folder % folder name
        scale % scale factor
        bigFile % File Size to adjust performance
        stlPresense % STL file presence
    end
    
    methods (Access = private)
        
        function results = stlread(app, stlFileName)
            results = true;

            try
                app.tr1 = stlread(stlFileName); %#ok<ADMTHDINV>
                app.filename = stlFileName;
            catch
                results = false;
            end
        end
        
        function results = stlscale(app, scale)
            tr1 = app.tr1; %#ok<ADPROPLC>
            results = true;

            try
                % scale for unit conversion
                % For instance, 0.001 means convertion from mm to m.
                Points = scale * tr1.Points; %#ok<ADPROPLC>
                tr2 = triangulation(tr1.ConnectivityList,Points); %#ok<ADPROPLC>
                app.tr2 = tr2; %#ok<ADPROPLC,ADPROPLC>
            catch
                results = false;
            end
        end
        
        function results = stlwrite(app)
            stlfilename = app.filename;
            tr2 = app.tr2; %#ok<ADPROP>
            results = true;
            
            try
                stlwrite(tr2,stlfilename) %#ok<ADPROP,ADPROP,ADMTHDINV>
            catch
                results = false;
            end
        end

        function results = showPresentTriangulation(app)

            ax = app.PresentUIAxes;
            results = true;

            if app.ShowPresentCheckBox.Value
                % show triangulation

                tr1 = app.tr1; %#ok<ADPROP>

                try
                    trisurf(tr1, 'Parent', ax) %#ok<ADPROP>
                    xlim(ax,"auto")
                    ylim(ax,"auto")
                    xticks(ax,"auto")
                    yticks(ax,"auto")
                    xticklabels(ax,"auto")
                    yticklabels(ax,"auto")
                    xlabel(ax,"X")
                    ylabel(ax,"Y")
                    axis(ax,"equal")
                catch
                    results = false;
                    app.showInvalid(ax);
                end

            else
                % show message to adjust performance

                % set 2D view
                plot(ax,0.0)
                view(ax,2);
                % UIAxes range
                xlim(ax, [0 1]);
                ylim(ax, [0 1]);
                % No label
                ax.XTick = [];
                ax.YTick = [];
                ax.XLabel.String = '';
                ax.YLabel.String = '';
                ax.Box = 'on';

                % Align text box at the center of the UIAxes
                text(ax, 0.5, 0.5, 'Disable', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 14);

            end
        end

        function results = showReducedTriangluation(app)
            tr2 = app.tr2; %#ok<ADPROP>
            ax = app.ScaleChangedUIAxes;
            results = true;

            try
                trisurf(tr2, 'Parent', ax) %#ok<ADPROP>
                xlim(ax,"auto")
                ylim(ax,"auto")
                xticks(ax,"auto")
                yticks(ax,"auto")
                xticklabels(ax,"auto")
                yticklabels(ax,"auto")
                xlabel(ax,"X")
                ylabel(ax,"Y")
                axis(ax,"equal")
            catch
                results = false;
                app.showInvalid(ax);
            end
        end
        
        function updateFileList(app, folderPath)

            stlFiles = dir(fullfile(folderPath, '*.stl')); % get stl file names
            if ~ispc
                % file name case sensitive
                stlFilesUpper = dir(fullfile(folderPath, '*.STL')); % get stl file names
                stlFiles = [stlFiles; stlFilesUpper];
            end

            app.stlPresense = ~isempty(stlFiles);

            if app.stlPresense
                fileList = {stlFiles.name}; % get file name list

                app.StlFileListBox.Items = fileList; % update ListBox
                app.folder = folderPath;    % update folder path
            else
                app.StlFileListBox.Items = "No STL files";
                app.showInvalid(app.PresentUIAxes);
                app.showInvalid(app.ScaleChangedUIAxes);
            end

            app.StlFileListBox.Enable = app.stlPresense;
            app.ScaleEditField.Enable = app.stlPresense;
            app.STLOverwriteButton.Enable = app.stlPresense;
        end
        
        function showInvalid(app, ax) %#ok<INUSD>
            % set 2D view
            plot(ax,0.0)
            view(ax,2);
            % UIAxes range
            xlim(ax, [0 1]);
            ylim(ax, [0 1]);
            % No label
            ax.XTick = [];
            ax.YTick = [];
            ax.XLabel.String = '';
            ax.YLabel.String = '';
            ax.Box = 'on';

            % Align text box at the center of the UIAxes
            text(ax, 0.5, 0.5, 'invalid', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 14);
        end
        
        function bigFile = checkFileSize(app, stlFileName) %#ok<INUSD>
            stlFile = dir(stlFileName); % get stl file name
            if stlFile.bytes > 10e6 % If > 10 MB, big
                bigFile = true;
            else
                bigFile = false;
            end

        end
        
        function progDialog = showProgressDialog(app)
            % show progress dialog
            progDialog = uiprogressdlg(app.UIFigure,'Title','In progress','Message','Wait a whole...','Indeterminate','on');
        end

        function closeProgressDialog(app, progDialog) %#ok<INUSD>
            if isempty(progDialog)
                return
            end

            % close progress dialog
            close(progDialog);
        end

        
        function updateStlFileSelection(app)
            if ~app.StlFileListBox.Enable
                return
            end

            value = app.StlFileListBox.Value;

            filename = fullfile(app.folder, value); %#ok<ADPROP>
            scale = app.scale; %#ok<ADPROP>
            app.bigFile = app.checkFileSize(filename); %#ok<ADPROP>

            if app.bigFile
                progDialog = app.showProgressDialog();
            else
                % progress dialog is not used
                progDialog = [];
            end

            results = app.stlread(filename); %#ok<ADPROP,ADPROP>
            if ~results
                app.showInvalid(app.PresentUIAxes);
                app.showInvalid(app.ScaleChangedUIAxes);
                app.closeProgressDialog(progDialog);
                return
            end
            results = app.showPresentTriangulation();
            if ~results
                app.showInvalid(app.ScaleChangedUIAxes);
                app.closeProgressDialog(progDialog);
                return
            end
            results = app.stlscale(scale); %#ok<ADPROP>
            if ~results
                app.showInvalid(app.ScaleChangedUIAxes);
                app.closeProgressDialog(progDialog);
                return
            end
            app.showReducedTriangluation();
            app.closeProgressDialog(progDialog);
        end
        
        
        function scaleChanged(app, scale, progDialog)
            app.scale = scale;
            results = app.stlscale(scale);

            if ~results
                app.showInvalid(app.ScaleChangedUIAxes);
                app.closeProgressDialog(progDialog);
                return
            end
            presentView = app.ScaleChangedUIAxes.View;
            app.showReducedTriangluation();
            app.ScaleChangedUIAxes.View = presentView;
            app.closeProgressDialog(progDialog);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.scale = app.ScaleEditField.Value;
            app.folder = pwd;
            app.bigFile = true;

            app.updateFileList(app.folder);
            app.updateStlFileSelection();
        end

        % Button pushed function: FolderSelectButton
        function FolderSelectButtonPushed(app, event)
            path = app.folder;

            folderPath = uigetdir(path); % Select Folder by GUI
            if folderPath == 0
                % If cancel, do nothing
                return;
            end
            
            app.updateFileList(folderPath);
            app.updateStlFileSelection();
        end

        % Value changed function: StlFileListBox
        function StlFileListBoxValueChanged(app, event)
            app.updateStlFileSelection();
        end

        % Value changed function: ShowPresentCheckBox
        function ShowPresentCheckBoxValueChanged(app, event)
            value = app.ShowPresentCheckBox.Value; %#ok<NASGU>
            
            if app.bigFile
                progDialog = app.showProgressDialog();
            else
                progDialog = [];
            end

            results = app.showPresentTriangulation();
            if ~results
                app.showInvalid(app.ScaleChangedUIAxes);
                app.closeProgressDialog(progDialog);
                return
            end
            app.closeProgressDialog(progDialog);
        end

        % Button pushed function: STLOverwriteButton
        function STLOverwriteButtonPushed(app, event)
            app.stlwrite();
            app.updateStlFileSelection();
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            value = app.ScaleEditField.Value;

            scale = value; %#ok<ADPROPLC>
            if scale < 1e-9 %#ok<ADPROPLC>
                scale = 1e-9; %#ok<ADPROPLC>
            elseif scale > 1e9 %#ok<ADPROPLC>
                scale = 1e9; %#ok<ADPROPLC>
            end

            if app.bigFile
                % if big file, show progress dialog during rendering update
                progDialog = app.showProgressDialog();
            else
                progDialog = [];
            end

            app.scaleChanged(scale, progDialog); %#ok<ADPROPLC>

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.FolderSelectButton = uibutton(app.GridLayout2, 'push');
            app.FolderSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelectButtonPushed, true);
            app.FolderSelectButton.Text = 'Folder Select';
            app.StlFileListBox = uilistbox(app.GridLayout2);
            app.StlFileListBox.ValueChangedFcn = createCallbackFcn(app, @StlFileListBoxValueChanged, true);
            app.ShowPresentCheckBox = uicheckbox(app.GridLayout2);
            app.ShowPresentCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPresentCheckBoxValueChanged, true);
            app.ShowPresentCheckBox.Text = 'Show Present';
            app.ShowPresentCheckBox.Value = true;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PresentUIAxes = uiaxes(app.GridLayout3);
            title(app.PresentUIAxes, 'Present')
            xlabel(app.PresentUIAxes, 'X')
            ylabel(app.PresentUIAxes, 'Y')
            zlabel(app.PresentUIAxes, 'Z')
            app.ScaleChangedUIAxes = uiaxes(app.GridLayout3);
            title(app.ScaleChangedUIAxes, 'Scale Changed')
            xlabel(app.ScaleChangedUIAxes, 'X')
            ylabel(app.ScaleChangedUIAxes, 'Y')
            zlabel(app.ScaleChangedUIAxes, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.STLOverwriteButton = uibutton(app.GridLayout4, 'push');
            app.STLOverwriteButton.ButtonPushedFcn = createCallbackFcn(app, @STLOverwriteButtonPushed, true);
            app.STLOverwriteButton.Text = 'STL Overwrite';
            app.ScaleEditFieldLabel = uilabel(app.GridLayout4);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ScaleEditField.Limits = [1e-09 1000000000];
            app.ScaleEditField.ValueDisplayFormat = '%9.9g';
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = 0.001;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StlScaleChanger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end